classdef Plot_AFAR_WQS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        PlotoptionPanel        matlab.ui.container.Panel
        NumReachID             matlab.ui.control.NumericEditField
        ReachIDEditFieldLabel  matlab.ui.control.Label
        PlotButton             matlab.ui.control.Button
        DisplayoptionsPanel    matlab.ui.container.Panel
        SelectColor            matlab.ui.control.DropDown
        ColorbarLabel          matlab.ui.control.Label
        Fig_Color              matlab.ui.control.UIAxes
        PlotType               matlab.ui.control.DropDown
        PlotTypeDropDownLabel  matlab.ui.control.Label
        Variable               matlab.ui.control.DropDown
        VariableDropDownLabel  matlab.ui.control.Label
        WQD                    matlab.ui.control.DropDown
        WaterqualitydeterminantsDropDownLabel  matlab.ui.control.Label
        Figure                 matlab.ui.control.UIAxes
        Label                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        AFAR_Obj % Description
    end
    
    methods (Access = public)
        
        function PosiProfile = SearchPosiProfile(app, ID)
            PosiProfile = find(app.AFAR_Obj.ReachID == ID);
            Posi = 1;
            while ~isempty(Posi)
                Posi = find(app.AFAR_Obj.FromNode == app.AFAR_Obj.ToNode(PosiProfile(end)));
                PosiProfile = [PosiProfile; Posi];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AFAR_Obj)
            % Input Data AFAR-WQS
            app.AFAR_Obj  = AFAR_Obj;
            
            WQN = { 'Temperature (T)',...
                    'Organic nitrogen (NO)',...
                    'Ammoniacal nitrogen (NH4)',...
                    'Nitrates (NO3)',...
                    'Organic phosphorus (PO)',...
                    'Inorganic phosphorus (PI)',...
                    'Organic matter (OM)',...
                    'Dissolved oxygen (DO)',...
                    'Suspended solids (SS)',...
                    'Pathogenic organisms (X)',...
                    'Elemental mercury (Hg0)',...
                    'Divalent mercury (Hg2)',...
                    'Methylmercury (MeHg)'};
            
            WQD_Status = [  AFAR_Obj.StatusWQS_T,...
                            AFAR_Obj.StatusWQS_NO,...
                            AFAR_Obj.StatusWQS_NH4,...
                            AFAR_Obj.StatusWQS_NO3,...
                            AFAR_Obj.StatusWQS_PO,...
                            AFAR_Obj.StatusWQS_PI,...
                            AFAR_Obj.StatusWQS_OM,...
                            AFAR_Obj.StatusWQS_DO,...
                            AFAR_Obj.StatusWQS_SS,...
                            AFAR_Obj.StatusWQS_X,...
                            AFAR_Obj.StatusWQS_Hg0,...
                            AFAR_Obj.StatusWQS_Hg2,...
                            AFAR_Obj.StatusWQS_MeHg];
            
            if sum(WQD_Status) == 0
                app.WQD.Items = 'No Models';
            else
                app.WQD.Items = WQN(WQD_Status);
            end
            
            if ~strcmp(app.WQD.Value,'No Models')
                if ~strcmp(app.WQD.Value,'Temperature (T)')
                    Value = {'Assimilation factors',...
                             'Concentration',...
                             'Load'};
                    app.Variable.Items = Value;
                else
                    Value = {'Concentration'};
                    app.Variable.Items = Value;
                end
                app.Variable.Enable = "on";
                app.PlotType.Enable = "on";
                app.DisplayoptionsPanel.Enable = "on";
            end

            % -------------------------------------------------------------
            % Configuration Figure
            % -------------------------------------------------------------
            box(app.Fig_Color,"off")
            set(app.Fig_Color,'Xcolor',[1 1 1],'Ycolor',[1 1 1])
            xticklabels(app.Fig_Color,'')
            yticklabels(app.Fig_Color,'')
            
            % -------------------------------------------------------------
            % Colormap
            % -------------------------------------------------------------
            NumCat = 64;

            % Create Colorbar
            StartColor  = [85, 255, 0]/255; % Green
            HalfColor   = [250 240 0]/255;  % Yellow
            EndColor    = [245, 0, 0]/255;  % Red
            
            % Crear el colormap interpolando entre los colores extremos
            CB1 = [linspace(StartColor(1), HalfColor(1), round(NumCat/2,0))', ...
                   linspace(StartColor(2), HalfColor(2), round(NumCat/2,0))', ...
                   linspace(StartColor(3), HalfColor(3), round(NumCat/2,0))'];

            CB2 = [linspace(HalfColor(1), EndColor(1), NumCat - round(NumCat/2,0) + 1)', ...
                   linspace(HalfColor(2), EndColor(2), NumCat - round(NumCat/2,0) + 1)', ...
                   linspace(HalfColor(3), EndColor(3), NumCat - round(NumCat/2,0) + 1)'];

            CB  = [CB1; CB2(2:end,:)];

            colormap(app.Fig_Color,CB);  % Asignar el colormap al UIAxes
            colorData = repmat(1:64, [8, 1]);  % Datos para crear una barra de colores
            imagesc(app.Fig_Color,colorData)

            % -------------------------------------------------------------
            % Plot Network
            % -------------------------------------------------------------            
            for i = 1:numel(AFAR_Obj.ReachID)                
                plot(app.Figure,AFAR_Obj.X{i},AFAR_Obj.Y{i}, 'Linewidth',1.2,'color',[0 169 230]/255);
                hold(app.Figure,"on")
            end

            % Plot Nodes
            scatter(app.Figure,AFAR_Obj.X_TN,AFAR_Obj.Y_TN,'filled','SizeData',15,'MarkerEdgeColor',[0 153 153]/255, 'MarkerFaceColor',[0 153 153]/255)

            % xlabel
            xlabel(app.Figure,'X coordinate (m)', 'interpreter','latex', 'Fontsize',18)
            % ylabel
            ylabel(app.Figure,'Y coordinate (m)', 'interpreter','latex', 'Fontsize',18)
            % Option
            set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)    
            
            % -------------------------------------------------------------
            % ReachID Default Plot
            % -------------------------------------------------------------
            app.NumReachID.Value = 929;%app.AFAR_Obj.RiverMouth(1);

            % Leg = legend(app.Figure,NameLegend);
            % set(Leg,'Fontsize',8)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Read water quality determinant
            NameWQD = app.WQD.Value;
            NameWQD = strsplit(NameWQD,'(');
            NameWQD = strrep(NameWQD{2},')','');
            
            % Read variable
            NameVar = app.Variable.Value;

            if strcmp(NameVar,'Assimilation factors')
                Data = app.AFAR_Obj.(['AF_',NameWQD]);
                NameLegBar = '(Liters)';
            elseif strcmp(NameVar,'Concentration')
                Data = app.AFAR_Obj.(['C_',NameWQD]);
                if strcmp(NameWQD,'T')
                    NameLegBar = '(Celsius)';
                elseif strcmp(NameWQD,'X')
                    NameLegBar = '(CFU-Colony/L)';
                else
                    NameLegBar = '(mg/L)';
                end
            elseif strcmp(NameVar,'Load')
                Data = app.AFAR_Obj.(['W_',NameWQD]);
                NameLegBar = '(mg)';
                if strcmp(NameWQD,'X')
                    NameLegBar = '(CFU-Colony)';
                end
            end
            
            if strcmp(app.PlotType.Value,'Water quality profile')
                PosiProfile = app.SearchPosiProfile(app.NumReachID.Value);
                Data = Data(PosiProfile);
            end
            
            % Reset figure  
            reset(app.Figure)
            cla(app.Figure,'reset');
            app.Figure.BackgroundColor = [1 1 1];
            hold(app.Figure,'off')
            
            % Number category
            NumCat  = 10;
            
            if strcmp('green-red',app.SelectColor.Value)
                % Create Colorbar
                StartColor  = [85, 255, 0]/255; % Green
                HalfColor   = [250 240 0]/255;  % Yellow
                EndColor    = [245, 0, 0]/255;  % Red
                
                % Crear el colormap interpolando entre los colores extremos
                CB1 = [linspace(StartColor(1), HalfColor(1), round(NumCat/2,0))', ...
                       linspace(StartColor(2), HalfColor(2), round(NumCat/2,0))', ...
                       linspace(StartColor(3), HalfColor(3), round(NumCat/2,0))'];
    
                CB2 = [linspace(HalfColor(1), EndColor(1), NumCat - round(NumCat/2,0) + 1)', ...
                       linspace(HalfColor(2), EndColor(2), NumCat - round(NumCat/2,0) + 1)', ...
                       linspace(HalfColor(3), EndColor(3), NumCat - round(NumCat/2,0) + 1)'];
    
                CB  = [CB1; CB2(2:end,:)];
            else
                CB = eval([app.SelectColor.Value,'(NumCat);']);
            end

            % Split Category
            ValueRange      = prctile(Data,linspace(0,100,NumCat+1));
            ValueRange(end) = ValueRange(end)*1.001;

            % -------------------------------------------------------------
            % Plot
            % ------------------------------------------------------------- 
            hold(app.Figure,"on")
            if strcmp(app.PlotType.Value,'Water quality profile')
                Len = cumsum(app.AFAR_Obj.L(PosiProfile))/1000;
                plot(app.Figure,Len,Data,'LineWidth',1.5,'Color',[0 0.5 0.5])

                if strcmp(NameWQD,'DO')&&strcmp(NameVar,'Concentration')                    
                    Ax1 = plot(app.Figure,Len,app.AFAR_Obj.C_OS(PosiProfile),'LineWidth',1.5,'Color',[0 0.7 0.7]);
                    Leg = legend(Ax1,'Saturation dissolved oxygen concentration');
                    set(Leg,'Fontsize',8)
                end

                % title
                title(app.Figure,['\bf Profile - ',app.WQD.Value], 'interpreter','latex', 'Fontsize',18)
                % xlabel
                xlabel(app.Figure,'\bf Length (km)', 'interpreter','latex', 'Fontsize',18)
                % ylabel
                ylabel(app.Figure,['\bf ',NameVar,' ',NameLegBar], 'interpreter','latex', 'Fontsize',18)
                % Option
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
            else
                for i = 1:numel(app.AFAR_Obj.ReachID)    
                    % Check range
                    Posi = ((Data(i) >= ValueRange(1:end-1)) & (Data(i) < ValueRange(2:end))); 
                    % Plot Reach
                    plot(app.Figure,app.AFAR_Obj.X{i},app.AFAR_Obj.Y{i}, 'Linewidth',1.2,'color',CB(Posi,:))
                end
    
                % Plot Nodes
                scatter(app.Figure,app.AFAR_Obj.X_TN,app.AFAR_Obj.Y_TN,'filled','SizeData',15,'MarkerEdgeColor',[0 153 153]/255, 'MarkerFaceColor',[0 153 153]/255)
                
                % Colorbar
                colormap(app.Figure,CB)
                c = colorbar(app.Figure);
                c.Ticks      = linspace(0,1,NumCat+1);
                c.TickLabels = cellfun(@(x) num2str(x,'%.2g'),num2cell(round(ValueRange,2)),'UniformOutput',false);
                c.TickLabelInterpreter = 'latex';
                
                % title
                title(app.Figure,['\bf ',NameVar,' - ',app.WQD.Value,' ',NameLegBar], 'interpreter','latex', 'Fontsize',18)
                % xlabel
                xlabel(app.Figure,'\bf X coordinate (m)', 'interpreter','latex', 'Fontsize',18)
                % ylabel
                ylabel(app.Figure,'\bf Y coordinate (m)', 'interpreter','latex', 'Fontsize',18)
                % Option
                set(app.Figure, 'TickLabelInterpreter','latex', 'FontWeight','bold','Color','none', 'box','on','FontSize',15)
            end
        end

        % Value changed function: SelectColor
        function SelectColorValueChanged(app, event)
            value = app.SelectColor.Value;
            if strcmp('green-red',value)
                NumCat = 64;

                % Create Colorbar
                StartColor  = [85, 255, 0]/255; % Green
                HalfColor   = [250 240 0]/255;  % Yellow
                EndColor    = [245, 0, 0]/255;  % Red
                
                CB1 = [linspace(StartColor(1), HalfColor(1), round(NumCat/2,0))', ...
                       linspace(StartColor(2), HalfColor(2), round(NumCat/2,0))', ...
                       linspace(StartColor(3), HalfColor(3), round(NumCat/2,0))'];
    
                CB2 = [linspace(HalfColor(1), EndColor(1), NumCat - round(NumCat/2,0) + 1)', ...
                       linspace(HalfColor(2), EndColor(2), NumCat - round(NumCat/2,0) + 1)', ...
                       linspace(HalfColor(3), EndColor(3), NumCat - round(NumCat/2,0) + 1)'];
    
                CB  = [CB1; CB2(2:end,:)];

                colormap(app.Fig_Color,CB); 
            else
                colormap(app.Fig_Color,value);                 
            end
            colorData = repmat(1:64, [8, 1]);
            imagesc(app.Fig_Color,colorData)
            
        end

        % Value changed function: PlotType
        function PlotTypeValueChanged(app, event)
            value = app.PlotType.Value;
            
            if strcmp('Water quality profile',value)
                app.NumReachID.Enable = "on";
                app.DisplayoptionsPanel.Enable = "off";
            else
                app.NumReachID.Enable = "off";
                app.DisplayoptionsPanel.Enable = "on";
            end
        end

        % Value changed function: NumReachID
        function NumReachIDValueChanged(app, event)
            value = app.NumReachID.Value;
            
            Check = sum(app.AFAR_Obj.ReachID == value);

            if Check == 0
                app.NumReachID.Value = app.AFAR_Obj.RiverMouth(1);
                errordlg('ReachID entered is not found')
            end

        end

        % Value changed function: WQD
        function WQDValueChanged(app, event)
           
             if ~strcmp(app.WQD.Value,'Temperature (T)')
                Value = {'Assimilation factors',...
                         'Concentration',...
                         'Load'};
                app.Variable.Items = Value;
            else
                Value = {'Concentration'};
                app.Variable.Items = Value;
             end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Assimilation Factor Analysis of Rivers for Water Quality Simulation - AFAR-WQS';
            app.Panel = uipanel(app.UIFigure);
            app.Figure = uiaxes(app.Panel);
            xlabel(app.Figure, 'Longitude')
            ylabel(app.Figure, 'Latitude')
            zlabel(app.Figure, 'Z')
            app.PlotoptionPanel = uipanel(app.Panel);
            app.PlotoptionPanel.Title = 'Plot option';
            app.WaterqualitydeterminantsDropDownLabel = uilabel(app.PlotoptionPanel);
            app.WaterqualitydeterminantsDropDownLabel.Text = 'Water quality determinants';
            app.WQD = uidropdown(app.PlotoptionPanel);
            app.WQD.Items = {'Temperature (T)', 'Conductivity (Co)', 'Organic nitrogen (NO)', 'Ammoniacal nitrogen (NH4)', 'Nitrates (NO3)', 'Organic phosphorus (PO)', 'Inorganic phosphorus (PI)', 'Organic matter (OM)', 'Dissolved oxygen (DO)', 'Suspended solids (SS)', 'Pathogenic organisms (X)', 'Elemental mercury (Hg0)', 'Divalent mercury (Hg2)', 'Methylmercury (MeHg)'};
            app.WQD.ValueChangedFcn = createCallbackFcn(app, @WQDValueChanged, true);
            app.WQD.Value = 'Suspended solids (SS)';
            app.VariableDropDownLabel = uilabel(app.PlotoptionPanel);
            app.VariableDropDownLabel.Text = 'Variable';
            app.Variable = uidropdown(app.PlotoptionPanel);
            app.Variable.Items = {'Assimilation factors', 'Concentration', 'Load'};
            app.Variable.Enable = 'off';
            app.Variable.Value = 'Concentration';
            app.PlotTypeDropDownLabel = uilabel(app.PlotoptionPanel);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotType = uidropdown(app.PlotoptionPanel);
            app.PlotType.Items = {'Spatial distribution', 'Water quality profile'};
            app.PlotType.ValueChangedFcn = createCallbackFcn(app, @PlotTypeValueChanged, true);
            app.PlotType.Enable = 'off';
            app.PlotType.Value = 'Spatial distribution';
            app.DisplayoptionsPanel = uipanel(app.PlotoptionPanel);
            app.DisplayoptionsPanel.Enable = 'off';
            app.DisplayoptionsPanel.Title = 'Display options';
            app.Fig_Color = uiaxes(app.DisplayoptionsPanel);
            app.ColorbarLabel = uilabel(app.DisplayoptionsPanel);
            app.ColorbarLabel.Text = 'Colorbar';
            app.SelectColor = uidropdown(app.DisplayoptionsPanel);
            app.SelectColor.Items = {'green-red', 'parula', 'jet', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.SelectColor.ValueChangedFcn = createCallbackFcn(app, @SelectColorValueChanged, true);
            app.SelectColor.Value = 'green-red';
            app.PlotButton = uibutton(app.PlotoptionPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ReachIDEditFieldLabel = uilabel(app.PlotoptionPanel);
            app.ReachIDEditFieldLabel.Text = 'ReachID';
            app.NumReachID = uieditfield(app.PlotoptionPanel, 'numeric');
            app.NumReachID.Limits = [0 Inf];
            app.NumReachID.ValueDisplayFormat = '%.0f';
            app.NumReachID.ValueChangedFcn = createCallbackFcn(app, @NumReachIDValueChanged, true);
            app.NumReachID.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_AFAR_WQS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end